classdef Initial_Orbit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SemiMajorAxiskmEditFieldLabel   matlab.ui.control.Label
        SemiMajorAxiskmEditField        matlab.ui.control.NumericEditField
        ArgumentofPerilunedegEditField_2Label  matlab.ui.control.Label
        ArgumentofPerilunedegEditField  matlab.ui.control.NumericEditField
        TrueAnomalydegEditFieldLabel    matlab.ui.control.Label
        TrueAnomalydegEditField         matlab.ui.control.NumericEditField
        eccentricityEditField_2Label    matlab.ui.control.Label
        eccentricityEditField           matlab.ui.control.NumericEditField
        RAANdegEditFieldLabel           matlab.ui.control.Label
        RAANdegEditField                matlab.ui.control.NumericEditField
        IncilationdegEditField_2Label   matlab.ui.control.Label
        IncilationdegEditField          matlab.ui.control.NumericEditField
        InsertInitialOrbitalElementsRadiusVectorVelocityVectorLabel  matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        ChooseTypeofInitialParametersButtonGroup  matlab.ui.container.ButtonGroup
        OrbitalElementButton            matlab.ui.control.RadioButton
        CartesianTODButton              matlab.ui.control.RadioButton
        xkmEditFieldLabel               matlab.ui.control.Label
        xkmEditField                    matlab.ui.control.NumericEditField
        ykmEditFieldLabel               matlab.ui.control.Label
        ykmEditField                    matlab.ui.control.NumericEditField
        zkmEditFieldLabel               matlab.ui.control.Label
        zkmEditField                    matlab.ui.control.NumericEditField
        vxkmsEditFieldLabel             matlab.ui.control.Label
        vxkmsEditField                  matlab.ui.control.NumericEditField
        vykmsEditFieldLabel             matlab.ui.control.Label
        vykmsEditField                  matlab.ui.control.NumericEditField
        vzkmsEditFieldLabel             matlab.ui.control.Label
        vzkmsEditField                  matlab.ui.control.NumericEditField
        StartatRAANCheckBox             matlab.ui.control.CheckBox
    end

    properties (Access = public)
        OrbitalElements0
        r0_and_v0
        flag_start_at_RAAN
        Flags_From_initial_orbit
    end
    
    properties (Access = private)
        CallingApp
    end
    
    methods (Access = public)
        
        function choose_type(app,selectedButton)
            
                app.flag_start_at_RAAN = 1; %default

            if strcmp(selectedButton, 'Orbital Element')
                app.xkmEditField.Enable = 'off';
                app.ykmEditField.Enable = 'off';
                app.zkmEditField.Enable = 'off';
                app.vxkmsEditField.Enable = 'off';
                app.vykmsEditField.Enable = 'off';
                app.vzkmsEditField.Enable = 'off';
                
                app.SemiMajorAxiskmEditField.Enable = 'on';
                app.ArgumentofPerilunedegEditField.Enable = 'on';
                app.eccentricityEditField.Enable = 'on';
                app.RAANdegEditField.Enable = 'on';
%                 app.TrueAnomalydegEditField.Enable = 'on';
                app.IncilationdegEditField.Enable = 'on';
                
            elseif strcmp(selectedButton, 'Cartesian (TOD)')
                app.SemiMajorAxiskmEditField.Enable = 'off';
                app.ArgumentofPerilunedegEditField.Enable = 'off';
                app.eccentricityEditField.Enable = 'off';
                app.RAANdegEditField.Enable = 'off';
%                 app.TrueAnomalydegEditField.Enable = 'off';
                app.IncilationdegEditField.Enable = 'off';
                
                app.xkmEditField.Enable = 'on';
                app.ykmEditField.Enable = 'on';
                app.zkmEditField.Enable = 'on';
                app.vxkmsEditField.Enable = 'on';
                app.vykmsEditField.Enable = 'on';
                app.vzkmsEditField.Enable = 'on';
            end
            
            start_at_RAAN = app.StartatRAANCheckBox.Value;            
            if start_at_RAAN
                app.TrueAnomalydegEditField.Enable = 'off';
            else
                if strcmp(selectedButton, 'Orbital Element')
                    app.TrueAnomalydegEditField.Enable = 'on';
                else
                    app.TrueAnomalydegEditField.Enable = 'off';
                end
            end
            
%                 app.TrueAnomalydegEditField.Enable = 'off';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, r_and_v, elements, Flags_From_Jeri)
            selectedButton = 'Orbital Element';
            choose_type(app,selectedButton)
            app.vxkmsEditFieldLabel.Interpreter = 'latex';
            app.vxkmsEditFieldLabel.Text= '$v_x$ [km/s]';
                        
            app.vykmsEditFieldLabel.Interpreter = 'latex';
            app.vykmsEditFieldLabel.Text= '$v_y$ [km/s]';
            
            app.vzkmsEditFieldLabel.Interpreter = 'latex';
            app.vzkmsEditFieldLabel.Text= '$v_z$ [km/s]';
            
            app.CallingApp = MainApp;
            
            app.xkmEditField.Value = r_and_v(1);
            app.ykmEditField.Value = r_and_v(2);
            app.zkmEditField.Value = r_and_v(3);
            
            app.vxkmsEditField.Value = r_and_v(4);
            app.vykmsEditField.Value = r_and_v(5);
            app.vzkmsEditField.Value = r_and_v(6);
            
            app.SemiMajorAxiskmEditField.Value = elements(1);
            app.ArgumentofPerilunedegEditField.Value = rad2deg(elements(2));
            app.TrueAnomalydegEditField.Value = rad2deg(elements(3));
            app.eccentricityEditField.Value = elements(4);
            app.RAANdegEditField.Value = rad2deg(elements(5));
            app.IncilationdegEditField.Value = rad2deg(elements(6));
            
            app.flag_start_at_RAAN = Flags_From_Jeri.Start_at_RAAN;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            a     = app.SemiMajorAxiskmEditField.Value;
            omega = app.ArgumentofPerilunedegEditField.Value;
            theta = app.TrueAnomalydegEditField.Value;
            
            ecc         = app.eccentricityEditField.Value;
            RAAN        = app.RAANdegEditField.Value;
            Inclination = app.IncilationdegEditField.Value;
            
            x = app.xkmEditField.Value;
            y = app.ykmEditField.Value;
            z = app.zkmEditField.Value;
            
            v_x = app.vxkmsEditField.Value;
            v_y = app.vykmsEditField.Value;
            v_z = app.vzkmsEditField.Value;
            
            r0 = [x, y, z];
            v0 = [v_x, v_y, v_z];
            
            app.OrbitalElements0 = [a, omega, theta, ecc, RAAN, Inclination];
            app.r0_and_v0        = [r0, v0];
            
            selectedButton = app.ChooseTypeofInitialParametersButtonGroup.SelectedObject.Text;
            if strcmp(selectedButton, 'Orbital Element')
                app.r0_and_v0 = nan(1,6);
            elseif strcmp(selectedButton, 'Cartesian (TOD)')
                app.OrbitalElements0 = nan(1,6);
            else
                errordlg('Check your Input Data','Error');
            end
            
            start_at_RAAN = app.StartatRAANCheckBox.Value;
            app.Flags_From_initial_orbit.Start_at_RAAN = start_at_RAAN;
            SendOrbitalData(app.CallingApp, app.OrbitalElements0, app.r0_and_v0, app.Flags_From_initial_orbit);            
            delete(app)
            
        end

        % Selection changed function: 
        % ChooseTypeofInitialParametersButtonGroup
        function ChooseTypeofInitialParametersButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChooseTypeofInitialParametersButtonGroup.SelectedObject.Text;
            choose_type(app,selectedButton)
        end

        % Value changed function: StartatRAANCheckBox
        function StartatRAANCheckBoxValueChanged(app, event)
            start_at_RAAN = app.StartatRAANCheckBox.Value;
            selectedButton = app.ChooseTypeofInitialParametersButtonGroup.SelectedObject.Text;

            if start_at_RAAN
                app.flag_start_at_RAAN = 1;
                app.TrueAnomalydegEditField.Enable = 'off';
            else
                app.flag_start_at_RAAN = 0;
                if strcmp(selectedButton, 'Orbital Element')
                    app.TrueAnomalydegEditField.Enable = 'on';
                else
                    app.TrueAnomalydegEditField.Enable = 'off';
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SemiMajorAxiskmEditFieldLabel = uilabel(app.UIFigure);
            app.SemiMajorAxiskmEditFieldLabel.Text = 'Semi-Major Axis [km]';
            app.SemiMajorAxiskmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ArgumentofPerilunedegEditField_2Label = uilabel(app.UIFigure);
            app.ArgumentofPerilunedegEditField_2Label.Text = 'Argument of Perilune [deg]';
            app.ArgumentofPerilunedegEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrueAnomalydegEditFieldLabel = uilabel(app.UIFigure);
            app.TrueAnomalydegEditFieldLabel.Text = 'True Anomaly [deg]';
            app.TrueAnomalydegEditField = uieditfield(app.UIFigure, 'numeric');
            app.eccentricityEditField_2Label = uilabel(app.UIFigure);
            app.eccentricityEditField_2Label.Text = 'eccentricity';
            app.eccentricityEditField = uieditfield(app.UIFigure, 'numeric');
            app.RAANdegEditFieldLabel = uilabel(app.UIFigure);
            app.RAANdegEditFieldLabel.Text = 'RAAN [deg]';
            app.RAANdegEditField = uieditfield(app.UIFigure, 'numeric');
            app.IncilationdegEditField_2Label = uilabel(app.UIFigure);
            app.IncilationdegEditField_2Label.Text = 'Incilation [deg]';
            app.IncilationdegEditField = uieditfield(app.UIFigure, 'numeric');
            app.InsertInitialOrbitalElementsRadiusVectorVelocityVectorLabel = uilabel(app.UIFigure);
            app.InsertInitialOrbitalElementsRadiusVectorVelocityVectorLabel.Text = 'Insert Initial Orbital Elements \ Radius Vector & Velocity Vector:';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ChooseTypeofInitialParametersButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseTypeofInitialParametersButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseTypeofInitialParametersButtonGroupSelectionChanged, true);
            app.ChooseTypeofInitialParametersButtonGroup.Title = 'Choose Type of Initial Parameters:';
            app.OrbitalElementButton = uiradiobutton(app.ChooseTypeofInitialParametersButtonGroup);
            app.OrbitalElementButton.Text = 'Orbital Element';
            app.OrbitalElementButton.Value = true;
            app.CartesianTODButton = uiradiobutton(app.ChooseTypeofInitialParametersButtonGroup);
            app.CartesianTODButton.Text = 'Cartesian (TOD)';
            app.xkmEditFieldLabel = uilabel(app.UIFigure);
            app.xkmEditFieldLabel.Text = 'x [km]';
            app.xkmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ykmEditFieldLabel = uilabel(app.UIFigure);
            app.ykmEditFieldLabel.Text = 'y [km]';
            app.ykmEditField = uieditfield(app.UIFigure, 'numeric');
            app.zkmEditFieldLabel = uilabel(app.UIFigure);
            app.zkmEditFieldLabel.Text = 'z [km]';
            app.zkmEditField = uieditfield(app.UIFigure, 'numeric');
            app.vxkmsEditFieldLabel = uilabel(app.UIFigure);
            app.vxkmsEditFieldLabel.Text = 'vx [km/s]';
            app.vxkmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.vykmsEditFieldLabel = uilabel(app.UIFigure);
            app.vykmsEditFieldLabel.Text = 'vy [km/s]';
            app.vykmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.vzkmsEditFieldLabel = uilabel(app.UIFigure);
            app.vzkmsEditFieldLabel.Text = 'vz [km/s]';
            app.vzkmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartatRAANCheckBox = uicheckbox(app.UIFigure);
            app.StartatRAANCheckBox.ValueChangedFcn = createCallbackFcn(app, @StartatRAANCheckBoxValueChanged, true);
            app.StartatRAANCheckBox.Text = 'Start at RAAN';
            app.StartatRAANCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Initial_Orbit(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end