classdef NOVASAT_MAIN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ChangeDefaultParametersButton  matlab.ui.control.StateButton
        NOVASATLabel                   matlab.ui.control.Label
        OutputPanel                    matlab.ui.container.Panel
        Lamp_7                         matlab.ui.control.Lamp
        Lamp_6                         matlab.ui.control.Lamp
        Lamp_5                         matlab.ui.control.Lamp
        Lamp_4                         matlab.ui.control.Lamp
        Lamp_3                         matlab.ui.control.Lamp
        Lamp_2                         matlab.ui.control.Lamp
        Lamp                           matlab.ui.control.Lamp
        LogicControlButton             matlab.ui.control.Button
        AttitudeControlButton          matlab.ui.control.Button
        OrbitButton                    matlab.ui.control.Button
        ThermalAnalysisButton          matlab.ui.control.Button
        MissionCameraButton            matlab.ui.control.Button
        CommunicationButton            matlab.ui.control.Button
        PowerButton                    matlab.ui.control.Button
        InputPanel                     matlab.ui.container.Panel
        hoursLabel                     matlab.ui.control.Label
        DurationEditField              matlab.ui.control.NumericEditField
        DurationEditFieldLabel         matlab.ui.control.Label
        IncludePerturbationsCheckBox   matlab.ui.control.CheckBox
        StartScenarioatButtonGroup     matlab.ui.container.ButtonGroup
        SafeModeButton                 matlab.ui.control.RadioButton
        OperationalDefaultButton       matlab.ui.control.RadioButton
        Label                          matlab.ui.control.Label
        OrbitCorrectionCheckBox        matlab.ui.control.CheckBox
        MissionCameraCheckBox          matlab.ui.control.CheckBox
        CommunicationCheckBox          matlab.ui.control.CheckBox
        InsertInitialAttitudeButton    matlab.ui.control.Button
        InsertInitialOrbitalElementsButton  matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        FinalTimeDatePicker            matlab.ui.control.DatePicker
        FinalTimeDatePickerLabel       matlab.ui.control.Label
        InitialTimeDatePicker          matlab.ui.control.DatePicker
        InitialTimeDatePickerLabel     matlab.ui.control.Label
    end

    
    properties (Access = public)
        Params_From_GUI
        Flags
        Attitude_Angles
        Attitude_rate
        r0_and_v0
        OrbitalElements0
        Mission_Start_Date
        Mission_End_Date
        Scenario_Duration_days
        SimData
        Flags_From_Jeri
        flag_start_at_RAAN
    end
    
    methods (Access = public)
        
        function SendAngularData(app, Attitude_Angles, Attitude_rate)
            app.Params_From_GUI.Attitude_Angles = Attitude_Angles;
            app.Params_From_GUI.Attitude_rate = Attitude_rate;
            app.InsertInitialAttitudeButton.Enable = 'on';
        end
        
        function SendOrbitalData(app, OrbitalElements0, r0_and_v0, Flags_From_initial_orbit)
            app.Params_From_GUI.OrbitalElements0 = OrbitalElements0;
            app.Params_From_GUI.r0_and_v0 = r0_and_v0;
            app.Flags.Start_at_RAAN = Flags_From_initial_orbit.Start_at_RAAN;
            app.InsertInitialOrbitalElementsButton.Enable = 'on';
         end
         
         function SetLimits(app)
             [DPFJ, ~] = JeriParams();
             app.Mission_Start_Date = DPFJ.Mission_Start_Date;
             app.Mission_End_Date = DPFJ.Mission_End_Date;
             app.Scenario_Duration_days = DPFJ.Scenario_Duration_days;
             Limits = [app.Mission_Start_Date,app.Mission_End_Date];
             app.InitialTimeDatePicker.Limits = Limits;
             app.FinalTimeDatePicker.Limits = Limits;
         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              SetLimits(app);
        end

        % Button pushed function: InsertInitialAttitudeButton
        function InsertInitialAttitudeButtonPushed(app, event)
            % DPFJ = dEFAULT Params from JeriParams
            % DPFJ = dEFAULT Flags from JeriParams
            [DPFJ, ~] = JeriParams();
            app.Attitude_Angles = DPFJ.Attitude_Angles0;
            app.Attitude_rate = DPFJ.Attitude_rate0;
            Initial_Attitude(app, app.Attitude_Angles, app.Attitude_rate)
            app.InsertInitialAttitudeButton.Enable = 'off';
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.Params_From_GUI.Scenario_Duration_hours = app.DurationEditField.Value;
            app.Label.Text = 'Working On It...';
            app.SimData = RunJericco(app.Params_From_GUI, app.Flags);
            app.Label.Text = 'Simulation has completed';
        end

        % Value changed function: InitialTimeDatePicker
        function InitialTimeDatePickerValueChanged(app, event)
            SetLimits(app);
            InitialTime = app.InitialTimeDatePicker.Value;
            app.FinalTimeDatePicker.Limits = [InitialTime, duration(days(app.Scenario_Duration_days-1))+InitialTime];
            app.Params_From_GUI.InitialTime = InitialTime;
        end

        % Value changed function: FinalTimeDatePicker
        function FinalTimeDatePickerValueChanged(app, event)
            FinalTime = app.FinalTimeDatePicker.Value;
            app.Params_From_GUI.FinalTime = FinalTime;
        end

        % Button pushed function: InsertInitialOrbitalElementsButton
        function InsertInitialOrbitalElementsButtonPushed(app, event)
            % DPFJ = dEFAULT Params from JeriParams
            % DFFJ = dEFAULT Flags from JeriParams
            [DPFJ, DFFJ] = JeriParams();
            app.r0_and_v0 = [DPFJ.r0, DPFJ.v0];
            app.OrbitalElements0 = [DPFJ.semi_major_axis0, DPFJ.omega0, DPFJ.theta0, DPFJ.ecc0, DPFJ.RAAN0, DPFJ.inc0];
            app.Flags_From_Jeri = DFFJ;
%             app.flag_start_at_RAAN = DFFJ.Start_at_RAAN;
            Initial_Orbit(app, app.r0_and_v0, app.OrbitalElements0, app.Flags_From_Jeri)
            app.InsertInitialOrbitalElementsButton.Enable = 'off';
        end

        % Value changed function: CommunicationCheckBox
        function CommunicationCheckBoxValueChanged(app, event)
            Comms_check_box = app.CommunicationCheckBox.Value;
            if Comms_check_box
                app.Flags.User_Comms = 1;
            else
                app.Flags.User_Comms = 0;
            end
        end

        % Value changed function: MissionCameraCheckBox
        function MissionCameraCheckBoxValueChanged(app, event)
            Mission_check_box = app.MissionCameraCheckBox.Value;
            if Mission_check_box
                app.Flags.User_Mission = 1;
            else
                app.Flags.User_Mission = 0;
            end
        end

        % Value changed function: OrbitCorrectionCheckBox
        function OrbitCorrectionCheckBoxValueChanged(app, event)
            OrbitCorr_check_box = app.MissionCameraCheckBox.Value;
            if OrbitCorr_check_box
                app.Flags.User_OrbitCorrection = 1;
            else
                app.Flags.User_OrbitCorrection = 0;
            end
        end

        % Value changed function: IncludePerturbationsCheckBox
        function IncludePerturbationsCheckBoxValueChanged(app, event)
            IncludePert = app.IncludePerturbationsCheckBox.Value;
            if IncludePert
                app.Flags.Include_Perturbations = 1;
            else
                app.Flags.Include_Perturbations = 0;
            end
        end

        % Button pushed function: OrbitButton
        function OrbitButtonPushed(app, event)
            [DPFJ, ~] = JeriParams();
            Output_Orbit(app,app.SimData,DPFJ)
        end

        % Button pushed function: PowerButton
        function PowerButtonPushed(app, event)
            [DPFJ, ~] = JeriParams();
            Output_Power(app,app.SimData,DPFJ)  
        end

        % Button pushed function: ThermalAnalysisButton
        function ThermalAnalysisButtonPushed(app, event)
            [DPFJ, ~] = JeriParams();
            Output_Thermal(app,app.SimData,DPFJ)      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.InitialTimeDatePickerLabel = uilabel(app.InputPanel);
            app.InitialTimeDatePickerLabel.Text = 'Initial Time:';
            app.InitialTimeDatePicker = uidatepicker(app.InputPanel);
            app.InitialTimeDatePicker.ValueChangedFcn = createCallbackFcn(app, @InitialTimeDatePickerValueChanged, true);
            app.FinalTimeDatePickerLabel = uilabel(app.InputPanel);
            app.FinalTimeDatePickerLabel.Text = 'Final Time:';
            app.FinalTimeDatePicker = uidatepicker(app.InputPanel);
            app.FinalTimeDatePicker.ValueChangedFcn = createCallbackFcn(app, @FinalTimeDatePickerValueChanged, true);
            app.RunButton = uibutton(app.InputPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.InsertInitialOrbitalElementsButton = uibutton(app.InputPanel, 'push');
            app.InsertInitialOrbitalElementsButton.ButtonPushedFcn = createCallbackFcn(app, @InsertInitialOrbitalElementsButtonPushed, true);
            app.InsertInitialOrbitalElementsButton.Text = 'Insert Initial Orbital Elements';
            app.InsertInitialAttitudeButton = uibutton(app.InputPanel, 'push');
            app.InsertInitialAttitudeButton.ButtonPushedFcn = createCallbackFcn(app, @InsertInitialAttitudeButtonPushed, true);
            app.InsertInitialAttitudeButton.Text = 'Insert Initial Attitude';
            app.CommunicationCheckBox = uicheckbox(app.InputPanel);
            app.CommunicationCheckBox.ValueChangedFcn = createCallbackFcn(app, @CommunicationCheckBoxValueChanged, true);
            app.CommunicationCheckBox.Text = 'Communication';
            app.MissionCameraCheckBox = uicheckbox(app.InputPanel);
            app.MissionCameraCheckBox.ValueChangedFcn = createCallbackFcn(app, @MissionCameraCheckBoxValueChanged, true);
            app.MissionCameraCheckBox.Text = 'Mission (Camera)';
            app.OrbitCorrectionCheckBox = uicheckbox(app.InputPanel);
            app.OrbitCorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @OrbitCorrectionCheckBoxValueChanged, true);
            app.OrbitCorrectionCheckBox.Text = 'Orbit Correction';
            app.Label = uilabel(app.InputPanel);
            app.Label.Text = '';
            app.StartScenarioatButtonGroup = uibuttongroup(app.InputPanel);
            app.StartScenarioatButtonGroup.Title = 'Start Scenario at:';
            app.OperationalDefaultButton = uiradiobutton(app.StartScenarioatButtonGroup);
            app.OperationalDefaultButton.Text = 'Operational (Default)';
            app.OperationalDefaultButton.Value = true;
            app.SafeModeButton = uiradiobutton(app.StartScenarioatButtonGroup);
            app.SafeModeButton.Text = 'Safe Mode';
            app.IncludePerturbationsCheckBox = uicheckbox(app.InputPanel);
            app.IncludePerturbationsCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludePerturbationsCheckBoxValueChanged, true);
            app.IncludePerturbationsCheckBox.Text = 'Include Perturbations';
            app.DurationEditFieldLabel = uilabel(app.InputPanel);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.InputPanel, 'numeric');
            app.hoursLabel = uilabel(app.InputPanel);
            app.hoursLabel.Text = 'hours';
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.PowerButton = uibutton(app.OutputPanel, 'push');
            app.PowerButton.ButtonPushedFcn = createCallbackFcn(app, @PowerButtonPushed, true);
            app.PowerButton.Text = 'Power';
            app.CommunicationButton = uibutton(app.OutputPanel, 'push');
            app.CommunicationButton.Text = 'Communication';
            app.MissionCameraButton = uibutton(app.OutputPanel, 'push');
            app.MissionCameraButton.Text = 'Mission (Camera)';
            app.ThermalAnalysisButton = uibutton(app.OutputPanel, 'push');
            app.ThermalAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ThermalAnalysisButtonPushed, true);
            app.ThermalAnalysisButton.Text = 'Thermal Analysis';
            app.OrbitButton = uibutton(app.OutputPanel, 'push');
            app.OrbitButton.ButtonPushedFcn = createCallbackFcn(app, @OrbitButtonPushed, true);
            app.OrbitButton.Text = 'Orbit';
            app.AttitudeControlButton = uibutton(app.OutputPanel, 'push');
            app.AttitudeControlButton.Text = 'Attitude Control';
            app.LogicControlButton = uibutton(app.OutputPanel, 'push');
            app.LogicControlButton.Text = 'Logic & Control';
            app.Lamp = uilamp(app.OutputPanel);
            app.Lamp_2 = uilamp(app.OutputPanel);
            app.Lamp_3 = uilamp(app.OutputPanel);
            app.Lamp_4 = uilamp(app.OutputPanel);
            app.Lamp_5 = uilamp(app.OutputPanel);
            app.Lamp_6 = uilamp(app.OutputPanel);
            app.Lamp_7 = uilamp(app.OutputPanel);
            app.NOVASATLabel = uilabel(app.UIFigure);
            app.NOVASATLabel.Text = 'NOVASAT';
            app.ChangeDefaultParametersButton = uibutton(app.UIFigure, 'state');
            app.ChangeDefaultParametersButton.Text = {'Change Default'; 'Parameters'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NOVASAT_MAIN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end