classdef Output_Thermal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ThermalAnalysisLabel         matlab.ui.control.Label
        Label_6                      matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        Lamp_12                      matlab.ui.control.Lamp
        Lamp_11                      matlab.ui.control.Lamp
        Lamp_10                      matlab.ui.control.Lamp
        Lamp_9                       matlab.ui.control.Lamp
        Lamp_8                       matlab.ui.control.Lamp
        Lamp_7                       matlab.ui.control.Lamp
        Lamp_6                       matlab.ui.control.Lamp
        Lamp_5                       matlab.ui.control.Lamp
        Lamp_4                       matlab.ui.control.Lamp
        Lamp_3                       matlab.ui.control.Lamp
        Lamp_2                       matlab.ui.control.Lamp
        Lamp                         matlab.ui.control.Lamp
        UpdateButton                 matlab.ui.control.Button
        ElectroOpticPayloadCheckBox  matlab.ui.control.CheckBox
        HeatersCheckBox              matlab.ui.control.CheckBox
        ReactionWheelsCheckBox       matlab.ui.control.CheckBox
        PropSystemCheckBox           matlab.ui.control.CheckBox
        IMUCheckBox                  matlab.ui.control.CheckBox
        SunSensorCheckBox            matlab.ui.control.CheckBox
        StarTrackerCheckBox          matlab.ui.control.CheckBox
        OBCCheckBox                  matlab.ui.control.CheckBox
        AntennaCheckBox              matlab.ui.control.CheckBox
        RXSbandCheckBox              matlab.ui.control.CheckBox
        TXxbandCheckBox              matlab.ui.control.CheckBox
        EPSCheckBox                  matlab.ui.control.CheckBox
        UIAxes_Q_all                 matlab.ui.control.UIAxes
        UIAxes_AveTemperature        matlab.ui.control.UIAxes
        UIAxes_q_net                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        SimData
        Params
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, SimData, Params)
            sec2hours = 1/3600;
            time_vector = SimData.time_vec(1:(end-1))*sec2hours;
            units_of_time_vector = ' [h]';
            num_of_digits = 2;
            
            %-------------------------------------------------------%
            
            hold(app.UIAxes_Q_all,'on')

            i = 1;
            comps =  fieldnames(SimData.Thermal.Internal_Q_component);
            while i <= length(comps)
                check_box_name = [char(comps(i)),'CheckBox'];
                check_box_name = strrep(check_box_name,'_','');
                    if  app.(check_box_name).Value
                        Q_comp_i = SimData.Thermal.Internal_Q_component.(char(comps(i)));
                        plot(app.UIAxes_Q_all,time_vector,round(Q_comp_i,num_of_digits),'-','LineWidth',2,'displayname',strrep(char(comps(i)),'_',' '));
                    end               
                i = i+1;

            end
            hold(app.UIAxes_Q_all,'off')
            legend(app.UIAxes_Q_all);
            
            
            app.UIAxes_Q_all.YLabel.Interpreter = 'latex';
            app.UIAxes_Q_all.YLabel.String = 'Heat Flux (W)';
            app.UIAxes_Q_all.XLim = [time_vector(1),time_vector(end)*1.5];
            app.UIAxes_Q_all.XLabel.String = ['Time ', units_of_time_vector];
            
            %-------------------------------------------------------%
            
            hold(app.UIAxes_AveTemperature,'on')
            max_temperature = Kel2Cel(Params.Temperature_max)*ones(size(time_vector));
            plot(app.UIAxes_AveTemperature,time_vector,round(max_temperature,num_of_digits),'--','LineWidth',2,'color',[0.6350 0.0780 0.1840],'displayname','Max Temperature')

            plot(app.UIAxes_AveTemperature,time_vector,round(Kel2Cel(SimData.Thermal.Average_Temperature),num_of_digits),'-','LineWidth',2,'color',[0 0.4470 0.7410],'displayname','Average Temperature')
            app.UIAxes_AveTemperature.YLabel.Interpreter = 'latex';
            app.UIAxes_AveTemperature.YLabel.String = ['Temperature (','$^{\circ}$C', ')'];
            app.UIAxes_AveTemperature.XLabel.String = ['Time ', units_of_time_vector];
            legend(app.UIAxes_AveTemperature);
            app.UIAxes_AveTemperature.XLim = [time_vector(1),time_vector(end)*1.8];
            hold(app.UIAxes_AveTemperature,'off')
            
%             hold(app.UIAxes_q_net,'on')
            plot(app.UIAxes_q_net,time_vector,round(SimData.Thermal.q_net,num_of_digits),'.','LineWidth',2,'displayname','q_{net}')
%             plot(app.UIAxes_q_net,time_vector,round(SimData.Power.Total_Power,num_of_digits),'.','LineWidth',2,'displayname','Total Power')
            app.UIAxes_q_net.YLabel.String = ['q_{net} ', '(W)'];
            app.UIAxes_q_net.XLabel.String = ['Time ', units_of_time_vector];
%             legend(app.UIAxes_Q_all);
%             hold(app.UIAxes_q_net,'off')
            
            app.UIAxes_AveTemperature.Box = 'on';
            app.UIAxes_q_net.Box = 'on';
            app.UIAxes_Q_all.Box = 'on';

            app.SimData = SimData;
            app.Params  = Params;
            
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            cla(app.UIAxes_Q_all)
            startupFcn(app, nan, app.SimData, app.Params)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_q_net = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_q_net, 'Time [s]')
            ylabel(app.UIAxes_q_net, 'q_neto ')
            zlabel(app.UIAxes_q_net, 'Z')
            app.UIAxes_AveTemperature = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_AveTemperature, 'Time [hr]')
            ylabel(app.UIAxes_AveTemperature, 'Average Temperature (C)')
            zlabel(app.UIAxes_AveTemperature, 'Z')
            app.UIAxes_Q_all = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_Q_all, 'Time [s]')
            ylabel(app.UIAxes_Q_all, 'Heat Flux (W)')
            zlabel(app.UIAxes_Q_all, 'Z')
            app.EPSCheckBox = uicheckbox(app.UIFigure);
            app.EPSCheckBox.Text = 'EPS';
            app.EPSCheckBox.Value = true;
            app.TXxbandCheckBox = uicheckbox(app.UIFigure);
            app.TXxbandCheckBox.Text = 'TX x band';
            app.TXxbandCheckBox.Value = true;
            app.RXSbandCheckBox = uicheckbox(app.UIFigure);
            app.RXSbandCheckBox.Text = 'RX S band';
            app.RXSbandCheckBox.Value = true;
            app.AntennaCheckBox = uicheckbox(app.UIFigure);
            app.AntennaCheckBox.Text = 'Antenna';
            app.AntennaCheckBox.Value = true;
            app.OBCCheckBox = uicheckbox(app.UIFigure);
            app.OBCCheckBox.Text = 'OBC';
            app.OBCCheckBox.Value = true;
            app.StarTrackerCheckBox = uicheckbox(app.UIFigure);
            app.StarTrackerCheckBox.Text = 'Star Tracker';
            app.StarTrackerCheckBox.Value = true;
            app.SunSensorCheckBox = uicheckbox(app.UIFigure);
            app.SunSensorCheckBox.Text = 'Sun Sensor';
            app.SunSensorCheckBox.Value = true;
            app.IMUCheckBox = uicheckbox(app.UIFigure);
            app.IMUCheckBox.Text = 'IMU';
            app.IMUCheckBox.Value = true;
            app.PropSystemCheckBox = uicheckbox(app.UIFigure);
            app.PropSystemCheckBox.Text = 'Prop System';
            app.PropSystemCheckBox.Value = true;
            app.ReactionWheelsCheckBox = uicheckbox(app.UIFigure);
            app.ReactionWheelsCheckBox.Text = 'Reaction Wheels';
            app.ReactionWheelsCheckBox.Value = true;
            app.HeatersCheckBox = uicheckbox(app.UIFigure);
            app.HeatersCheckBox.Text = 'Heaters';
            app.HeatersCheckBox.Value = true;
            app.ElectroOpticPayloadCheckBox = uicheckbox(app.UIFigure);
            app.ElectroOpticPayloadCheckBox.Text = 'Electro Optic Payload';
            app.ElectroOpticPayloadCheckBox.Value = true;
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_4 = uilamp(app.UIFigure);
            app.Lamp_5 = uilamp(app.UIFigure);
            app.Lamp_6 = uilamp(app.UIFigure);
            app.Lamp_7 = uilamp(app.UIFigure);
            app.Lamp_8 = uilamp(app.UIFigure);
            app.Lamp_9 = uilamp(app.UIFigure);
            app.Lamp_10 = uilamp(app.UIFigure);
            app.Lamp_11 = uilamp(app.UIFigure);
            app.Lamp_12 = uilamp(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '(2)';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '(2)';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '(2)';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '(2)';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '(2)';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '(4)';
            app.ThermalAnalysisLabel = uilabel(app.UIFigure);
            app.ThermalAnalysisLabel.Text = 'Thermal Analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Output_Thermal(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end