classdef Initial_Attitude < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        PsidegEditFieldLabel    matlab.ui.control.Label
        PsidegEditField         matlab.ui.control.NumericEditField
        ThetadegEditFieldLabel  matlab.ui.control.Label
        ThetadegEditField       matlab.ui.control.NumericEditField
        PhidegEditFieldLabel    matlab.ui.control.Label
        PhidegEditField         matlab.ui.control.NumericEditField
        PdegsEditFieldLabel     matlab.ui.control.Label
        PdegsEditField          matlab.ui.control.NumericEditField
        QdegsEditFieldLabel     matlab.ui.control.Label
        QdegsEditField          matlab.ui.control.NumericEditField
        RdegsEditFieldLabel     matlab.ui.control.Label
        RdegsEditField          matlab.ui.control.NumericEditField
        InsertInitialAngularStateBodyFrameLabel  matlab.ui.control.Label
        Image                   matlab.ui.control.Image
        SaveButton              matlab.ui.control.Button
    end

    properties (Access = public)
        AngularState_angle
        AngularState_rate
    end  
    
    properties (Access = private)
        CallingApp
    end            


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, AngularState_angle, AngularState_rate)
            app.PsidegEditFieldLabel.Interpreter = 'latex';
            app.PsidegEditFieldLabel.Text= '$\psi$ [deg]';
            
            app.ThetadegEditFieldLabel.Interpreter = 'latex';
            app.ThetadegEditFieldLabel.Text= '$\theta$ [deg]';
            
            app.PhidegEditFieldLabel.Interpreter = 'latex';
            app.PhidegEditFieldLabel.Text= '$\phi$ [deg]';
            
            app.Image.ImageSource = [pwd, '\General\JERI_BODY.png'];

            app.CallingApp = MainApp;
            
            app.PsidegEditField.Value = AngularState_angle(1);
            app.ThetadegEditField.Value = AngularState_angle(2);
            app.PhidegEditField.Value = AngularState_angle(3);
            
            app.PdegsEditField.Value = AngularState_rate(1);
            app.QdegsEditField.Value = AngularState_rate(2);
            app.PdegsEditField.Value = AngularState_rate(3);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            psi0   = app.PsidegEditField.Value;
            thete0 = app.ThetadegEditField.Value;
            phi0   = app.PhidegEditField.Value;
            
            P = app.PdegsEditField.Value;
            Q = app.QdegsEditField.Value;
            R = app.PdegsEditField.Value;

            app.AngularState_angle = [psi0,thete0,phi0];
            app.AngularState_rate  = [P, Q, R];
            
            SendAngularData(app.CallingApp, app.AngularState_angle, app.AngularState_rate);

            delete(app)
                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PsidegEditFieldLabel = uilabel(app.UIFigure);
            app.PsidegEditFieldLabel.Text = 'Psi [deg]';
            app.PsidegEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThetadegEditFieldLabel = uilabel(app.UIFigure);
            app.ThetadegEditFieldLabel.Text = 'Theta [deg]';
            app.ThetadegEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhidegEditFieldLabel = uilabel(app.UIFigure);
            app.PhidegEditFieldLabel.Text = 'Phi [deg]';
            app.PhidegEditField = uieditfield(app.UIFigure, 'numeric');
            app.PdegsEditFieldLabel = uilabel(app.UIFigure);
            app.PdegsEditFieldLabel.Text = 'P [deg/s]';
            app.PdegsEditField = uieditfield(app.UIFigure, 'numeric');
            app.QdegsEditFieldLabel = uilabel(app.UIFigure);
            app.QdegsEditFieldLabel.Text = 'Q [deg/s]';
            app.QdegsEditField = uieditfield(app.UIFigure, 'numeric');
            app.RdegsEditFieldLabel = uilabel(app.UIFigure);
            app.RdegsEditFieldLabel.Text = 'R [deg/s]';
            app.RdegsEditField = uieditfield(app.UIFigure, 'numeric');
            app.InsertInitialAngularStateBodyFrameLabel = uilabel(app.UIFigure);
            app.InsertInitialAngularStateBodyFrameLabel.Text = 'Insert Initial Angular State (Body Frame):';
            app.Image = uiimage(app.UIFigure);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Initial_Attitude(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end