classdef Output_Orbit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        PlotOrbit3DButton   matlab.ui.control.Button
        OrbitLabel          matlab.ui.control.Label
        UIAxes_velocity     matlab.ui.control.UIAxes
        UIAxes_RAAN         matlab.ui.control.UIAxes
        UIAxes_TrueAnomaly  matlab.ui.control.UIAxes
        UIAxes_ArgOfPeri    matlab.ui.control.UIAxes
        UIAxes_SMA          matlab.ui.control.UIAxes
        UIAxes_Inc          matlab.ui.control.UIAxes
        UIAxes_ecc          matlab.ui.control.UIAxes
        UIAxes_Altitude     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        SimData
        Params
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, SimData, Params)
            sec2hours = 1/3600;
            if isfield (SimData,'t_orbit')
                time_vector = SimData.t_orbit*sec2hours;
            else
                time_vector = SimData.time_vec(2:end)*sec2hours;
            end
            
            units_of_time_vector = ' [h]';
            num_of_digits = 2;
            
            plot(app.UIAxes_Altitude,time_vector,round(SimData.orbit.Altitude,num_of_digits),'.-','LineWidth',2,'displayname','Altitude')
            hold(app.UIAxes_Altitude,'on')
            plot(app.UIAxes_Altitude,time_vector,round(SimData.orbit.Peri_Altitude,num_of_digits),'--','LineWidth',2,'displayname','Perilune Altitude')
            plot(app.UIAxes_Altitude,time_vector,round(SimData.orbit.Apo_Altitude,num_of_digits),'--','LineWidth',2,'displayname','Apolune Altitude')
            legend(app.UIAxes_Altitude);
            app.UIAxes_Altitude.YLim = [min(SimData.orbit.Peri_Altitude)*0.999,max(SimData.orbit.Apo_Altitude)*1.005];
            app.UIAxes_Altitude.XLabel.String = ['Time ', units_of_time_vector];
            app.UIAxes_Altitude.Box = 'on';
            hold(app.UIAxes_Altitude,'off')
            
            plot(app.UIAxes_velocity,time_vector,round(Norm_Each_Row(SimData.orbit.v),2),'.','LineWidth',2)
            app.UIAxes_velocity.XLabel.String = ['Time ', units_of_time_vector];
            
            plot(app.UIAxes_RAAN,time_vector,round(rad2deg(SimData.orbit.RAAN),num_of_digits),'.','LineWidth',2)
            plot(app.UIAxes_SMA,time_vector,round(SimData.orbit.SMA,num_of_digits),'.','LineWidth',2)
            plot(app.UIAxes_TrueAnomaly,time_vector,round(rad2deg(SimData.orbit.TrueAnomaly),num_of_digits),'.','LineWidth',2)
            plot(app.UIAxes_Inc,time_vector,round(rad2deg(SimData.orbit.inc),num_of_digits),'.','LineWidth',2)
            %             plot(app.UIAxes_ArgOfPeri,time_vector,round(rad2deg(SimData.orbit.omega),num_of_digits),'.','LineWidth',2)
%             plot(app.UIAxes_ecc,time_vector,round(SimData.orbit.ecc,num_of_digits),'.','LineWidth',2)
                        plot(app.UIAxes_ecc,time_vector,SimData.orbit.ecc,'.','LineWidth',2)
            plot(app.UIAxes_ArgOfPeri,time_vector,rad2deg(SimData.orbit.omega),'.','LineWidth',2)
            
            app.UIAxes_RAAN.XLabel.String = ['Time ', units_of_time_vector];
            app.UIAxes_SMA.XLabel.String = ['Time ', units_of_time_vector];
            app.UIAxes_TrueAnomaly.XLabel.String = ['Time ', units_of_time_vector];
            app.UIAxes_Inc.XLabel.String = ['Time ', units_of_time_vector];
            app.UIAxes_ecc.XLabel.String = ['Time ', units_of_time_vector];
            app.UIAxes_ArgOfPeri.XLabel.String = ['Time ', units_of_time_vector];
            
            app.UIAxes_RAAN.Box = 'on';
            app.UIAxes_SMA.Box = 'on';
            app.UIAxes_TrueAnomaly.Box = 'on';
            app.UIAxes_Inc.Box = 'on';
            app.UIAxes_ecc.Box = 'on';
            app.UIAxes_ArgOfPeri.Box = 'on';
            
            app.SimData = SimData;
            app.Params  = Params;
        end

        % Button pushed function: PlotOrbit3DButton
        function PlotOrbit3DButtonPushed(app, event)
            Plot_Orbit_and_Moon(app.SimData, app.Params)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlotOrbit3DButton = uibutton(app.UIFigure, 'push');
            app.PlotOrbit3DButton.ButtonPushedFcn = createCallbackFcn(app, @PlotOrbit3DButtonPushed, true);
            app.PlotOrbit3DButton.Text = 'Plot Orbit (3D)';
            app.OrbitLabel = uilabel(app.UIFigure);
            app.OrbitLabel.Text = 'Orbit';
            app.UIAxes_velocity = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_velocity, 'Time [s]')
            ylabel(app.UIAxes_velocity, 'Velocity [km/s]')
            zlabel(app.UIAxes_velocity, 'Z')
            app.UIAxes_RAAN = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_RAAN, 'Time [s]')
            ylabel(app.UIAxes_RAAN, 'RAAN [deg]')
            zlabel(app.UIAxes_RAAN, 'Z')
            app.UIAxes_TrueAnomaly = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_TrueAnomaly, 'Time [s]')
            ylabel(app.UIAxes_TrueAnomaly, 'True Anomaly [deg]')
            zlabel(app.UIAxes_TrueAnomaly, 'Z')
            app.UIAxes_ArgOfPeri = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_ArgOfPeri, 'Time [s]')
            ylabel(app.UIAxes_ArgOfPeri, 'Argument of Perilune [deg]')
            zlabel(app.UIAxes_ArgOfPeri, 'Z')
            app.UIAxes_SMA = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_SMA, 'Time [s]')
            ylabel(app.UIAxes_SMA, 'Semi-Major Axis [km]')
            zlabel(app.UIAxes_SMA, 'Z')
            app.UIAxes_Inc = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_Inc, 'Time [s]')
            ylabel(app.UIAxes_Inc, 'Inclination [deg]')
            zlabel(app.UIAxes_Inc, 'Z')
            app.UIAxes_ecc = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_ecc, 'Time [s]')
            ylabel(app.UIAxes_ecc, 'Eccentricity')
            zlabel(app.UIAxes_ecc, 'Z')
            app.UIAxes_Altitude = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_Altitude, 'Time [s]')
            ylabel(app.UIAxes_Altitude, 'Altitude [km]')
            zlabel(app.UIAxes_Altitude, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Output_Orbit(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end