classdef Output_Power < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        EPSCheckBox                  matlab.ui.control.CheckBox
        TXxbandCheckBox              matlab.ui.control.CheckBox
        RXSbandCheckBox              matlab.ui.control.CheckBox
        AntennaCheckBox              matlab.ui.control.CheckBox
        OBCCheckBox                  matlab.ui.control.CheckBox
        StarTrackerCheckBox          matlab.ui.control.CheckBox
        SunSensorCheckBox            matlab.ui.control.CheckBox
        IMUCheckBox                  matlab.ui.control.CheckBox
        PropSystemCheckBox           matlab.ui.control.CheckBox
        ReactionWheelsCheckBox       matlab.ui.control.CheckBox
        HeatersCheckBox              matlab.ui.control.CheckBox
        ElectroOpticPayloadCheckBox  matlab.ui.control.CheckBox
        UpdateButton                 matlab.ui.control.Button
        TotalPowerCheckBox           matlab.ui.control.CheckBox
        Label                        matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        Label_6                      matlab.ui.control.Label
        ElectricalSystemPerformanceLabel  matlab.ui.control.Label
        UIAxes_Power_Production      matlab.ui.control.UIAxes
        UIAxes_Charge                matlab.ui.control.UIAxes
        UIAxes_Power_cons            matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        SimData
        Params
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, SimData, Params)
            sec2hours = 1/3600;
            time_vector = SimData.time_vec(1:(end-1))*sec2hours;
            units_of_time_vector = ' [h]';
            num_of_digits = 2;
            
            hold(app.UIAxes_Power_cons,'on')
            
            i = 1;
            comps =  fieldnames(SimData.Power);
            while i <= length(comps)
                check_box_name = [char(comps(i)),'CheckBox'];
                check_box_name = strrep(check_box_name,'_','');
                if ~strcmp(char(comps(i)),'Production')
                    if  app.(check_box_name).Value
                        plot(app.UIAxes_Power_cons,time_vector,round(SimData.Power.(char(comps(i))),num_of_digits),'-','LineWidth',2,'displayname',strrep(char(comps(i)),'_',' '));
                    end
                end
                
                i = i+1;
            end
            
            
            hold(app.UIAxes_Power_cons,'off')
            app.UIAxes_Power_cons.XLabel.String = ['Time ', units_of_time_vector];
            
            plot(app.UIAxes_Charge,time_vector,round(SimData.Batteries_Electric_Charge,num_of_digits),'.','LineWidth',2)
            app.UIAxes_Charge.XLabel.String = ['Time ', units_of_time_vector];
            
            plot(app.UIAxes_Power_Production,time_vector,round(SimData.Power.Production,num_of_digits),'.','LineWidth',2)
            app.UIAxes_Power_Production.XLabel.String = ['Time ', units_of_time_vector];
            
            app.UIAxes_Power_cons.Box = 'on';
            app.UIAxes_Charge.Box = 'on';
            app.UIAxes_Power_Production.Box = 'on';
            
            app.SimData = SimData;
            app.Params  = Params;
            legend(app.UIAxes_Power_cons);
            app.UIAxes_Power_cons.XLim = [time_vector(1),time_vector(end)*1.5];
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            cla(app.UIAxes_Power_cons)
            startupFcn(app, nan, app.SimData, app.Params)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EPSCheckBox = uicheckbox(app.UIFigure);
            app.EPSCheckBox.Text = 'EPS';
            app.EPSCheckBox.Value = true;
            app.TXxbandCheckBox = uicheckbox(app.UIFigure);
            app.TXxbandCheckBox.Text = 'TX x band';
            app.TXxbandCheckBox.Value = true;
            app.RXSbandCheckBox = uicheckbox(app.UIFigure);
            app.RXSbandCheckBox.Text = 'RX S band';
            app.RXSbandCheckBox.Value = true;
            app.AntennaCheckBox = uicheckbox(app.UIFigure);
            app.AntennaCheckBox.Text = 'Antenna';
            app.AntennaCheckBox.Value = true;
            app.OBCCheckBox = uicheckbox(app.UIFigure);
            app.OBCCheckBox.Text = 'OBC';
            app.OBCCheckBox.Value = true;
            app.StarTrackerCheckBox = uicheckbox(app.UIFigure);
            app.StarTrackerCheckBox.Text = 'Star Tracker';
            app.StarTrackerCheckBox.Value = true;
            app.SunSensorCheckBox = uicheckbox(app.UIFigure);
            app.SunSensorCheckBox.Text = 'Sun Sensor';
            app.SunSensorCheckBox.Value = true;
            app.IMUCheckBox = uicheckbox(app.UIFigure);
            app.IMUCheckBox.Text = 'IMU';
            app.IMUCheckBox.Value = true;
            app.PropSystemCheckBox = uicheckbox(app.UIFigure);
            app.PropSystemCheckBox.Text = 'Prop System';
            app.PropSystemCheckBox.Value = true;
            app.ReactionWheelsCheckBox = uicheckbox(app.UIFigure);
            app.ReactionWheelsCheckBox.Text = 'Reaction Wheels';
            app.ReactionWheelsCheckBox.Value = true;
            app.HeatersCheckBox = uicheckbox(app.UIFigure);
            app.HeatersCheckBox.Text = 'Heaters';
            app.HeatersCheckBox.Value = true;
            app.ElectroOpticPayloadCheckBox = uicheckbox(app.UIFigure);
            app.ElectroOpticPayloadCheckBox.Text = 'Electro Optic Payload';
            app.ElectroOpticPayloadCheckBox.Value = true;
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.TotalPowerCheckBox = uicheckbox(app.UIFigure);
            app.TotalPowerCheckBox.Text = 'Total Power';
            app.TotalPowerCheckBox.Value = true;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '(2)';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '(2)';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '(2)';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '(2)';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '(2)';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '(4)';
            app.ElectricalSystemPerformanceLabel = uilabel(app.UIFigure);
            app.ElectricalSystemPerformanceLabel.Text = 'Electrical System Performance';
            app.UIAxes_Power_Production = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_Power_Production, 'Time [s]')
            ylabel(app.UIAxes_Power_Production, 'Power Production [W]')
            zlabel(app.UIAxes_Power_Production, 'Z')
            app.UIAxes_Charge = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_Charge, 'Time [hr]')
            ylabel(app.UIAxes_Charge, 'Charge in Batteries [Wh]')
            zlabel(app.UIAxes_Charge, 'Z')
            app.UIAxes_Power_cons = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_Power_cons, 'Time [s]')
            ylabel(app.UIAxes_Power_cons, 'Power Consumption [W]')
            zlabel(app.UIAxes_Power_cons, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Output_Power(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end